classdef ADXL335CalibrationProgram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        SaveButton                 matlab.ui.control.Button
        ix                         matlab.ui.container.GridLayout
        CDXBtn                     matlab.ui.control.StateButton
        CUXBtn                     matlab.ui.control.StateButton
        GridLayout2_8              matlab.ui.container.GridLayout
        ZSensLabel                 matlab.ui.control.Label
        ZMeanLabel                 matlab.ui.control.Label
        GridLayout2_7              matlab.ui.container.GridLayout
        YSensLabel_2               matlab.ui.control.Label
        YMeanLabel                 matlab.ui.control.Label
        GridLayout2_6              matlab.ui.container.GridLayout
        ResZSens                   matlab.ui.control.Label
        ResZMean                   matlab.ui.control.Label
        GridLayout2_5              matlab.ui.container.GridLayout
        ResYSens                   matlab.ui.control.Label
        ResYMean                   matlab.ui.control.Label
        GridLayout2_4              matlab.ui.container.GridLayout
        ResXSens                   matlab.ui.control.Label
        ResXMean                   matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        XSensLabel                 matlab.ui.control.Label
        XMeanLabel                 matlab.ui.control.Label
        BaudrateEditField          matlab.ui.control.NumericEditField
        BaudrateEditFieldLabel     matlab.ui.control.Label
        ScnBtn                     matlab.ui.control.Button
        CBtn                       matlab.ui.control.Button
        SDDD                       matlab.ui.control.DropDown
        SerialDeviceDropDownLabel  matlab.ui.control.Label
        iz                         matlab.ui.container.GridLayout
        CDZBtn                     matlab.ui.control.StateButton
        CUZBtn                     matlab.ui.control.StateButton
        iy                         matlab.ui.container.GridLayout
        CUYBtn                     matlab.ui.control.StateButton
        CDYBtn                     matlab.ui.control.StateButton
        ax                         matlab.ui.control.UIAxes
        ay                         matlab.ui.control.UIAxes
        az                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Animated lines
        hx
        hy
        hz
        serial_interface
        is_recording
        x_mean_up
        x_mean_down
        y_mean_up
        y_mean_down
        z_mean_up
        z_mean_down

        x_m
        y_m
        z_m
        x_s
        y_s
        z_s
    end
    
    methods (Access = private)
        
        function results = start_recording(app)
            app.serial_interface.received_data = [];
            clearpoints(app.hx)
            clearpoints(app.hy)
            clearpoints(app.hz)
            pause(0.1)
            lastindex = 1;
            while app.is_recording == true
                addpoints(app.hx,app.serial_interface.received_data(1,lastindex:end),...
                            app.serial_interface.received_data(2,lastindex:end));
                addpoints(app.hy,app.serial_interface.received_data(1,lastindex:end),...
                            app.serial_interface.received_data(3,lastindex:end));
                addpoints(app.hz,app.serial_interface.received_data(1,lastindex:end),...
                            app.serial_interface.received_data(4,lastindex:end));
                lastindex = size(app.serial_interface.received_data,2);
                pause(0.2)
                drawnow
            end
        end
        
        function results = stop_recording(app)
            app.is_recording = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            app.hx = animatedline(Parent=app.ax);
            app.hy = animatedline(Parent=app.ay);
            app.hz = animatedline(Parent=app.az);
            app.is_recording = false;
        end

        % Button pushed function: ScnBtn
        function ScnBtnButtonPushed(app, event)
            app.SDDD.Items = serialportlist("available");
        end

        % Button pushed function: CBtn
        function CBtnButtonPushed(app, event)
            if(~isempty(app.serial_interface))
                app.serial_interface.is_reading()
            end
            if(isempty(app.serial_interface))
                app.serial_interface = ADXLInterface(app.SDDD.Value,app.BaudrateEditField.Value);
                app.CBtn.Text = "Disconnect";
            else
                app.CBtn.Text = "Connect";
                app.serial_interface.delete()
                clear app.serial_interface
            end
        end

        % Value changed function: CDXBtn, CDYBtn, CDZBtn, CUXBtn, CUYBtn, 
        % ...and 1 other component
        function CUXBtnValueChanged(app, event)
            value = event.Source.Value;
            if value == 1
                app.serial_interface.start_reading()
                app.is_recording = true;
                app.start_recording()
            else
                app.serial_interface.stop_reading()
                app.is_recording = false;
                app.stop_recording();
                if event.Source==app.CUXBtn
                    app.x_mean_up = mean(double(app.serial_interface.received_data(2,1:end)));
                    if(~isempty(app.x_mean_down))
                        app.x_m = double(app.x_mean_up + app.x_mean_down)/2;
                        app.x_s = 9.81*2/double(app.x_mean_up - app.x_mean_down);
                        app.ResXMean.Text = sprintf("%.2f",app.x_m);
                        app.ResXSens.Text = sprintf("%.2f",app.x_s);
                    end
                elseif event.Source==app.CDXBtn
                    app.x_mean_down = mean(double(app.serial_interface.received_data(2,1:end)));
                    if(~isempty(app.x_mean_up))
                        app.x_m = double(app.x_mean_up + app.x_mean_down)/2;
                        app.x_s = 9.81*2/double(app.x_mean_up - app.x_mean_down);
                        app.ResXMean.Text = sprintf("%.2f",app.x_m);
                        app.ResXSens.Text = sprintf("%.2f",app.x_s);
                    end
                elseif event.Source==app.CUYBtn
                    app.y_mean_up = mean(double(app.serial_interface.received_data(3,1:end)));
                    if(~isempty(app.y_mean_down))
                        app.y_m = double(app.y_mean_up + app.y_mean_down)/2;
                        app.y_s = 9.81*2/double(app.y_mean_up - app.y_mean_down);
                        app.ResYMean.Text = sprintf("%.2f",app.y_m);
                        app.ResYSens.Text = sprintf("%.2f",app.y_s);
                    end
                elseif event.Source==app.CDYBtn
                    app.y_mean_down = mean(double(app.serial_interface.received_data(3,1:end)));
                    if(~isempty(app.y_mean_up))
                        app.y_m = double(app.y_mean_up + app.y_mean_down)/2;
                        app.y_s = 9.81*2/double(app.y_mean_up - app.y_mean_down);
                        app.ResYMean.Text = sprintf("%.2f",app.y_m);
                        app.ResYSens.Text = sprintf("%.2f",app.y_s);
                    end
                elseif event.Source==app.CUZBtn
                    app.z_mean_up = mean(double(app.serial_interface.received_data(4,1:end)));
                    if(~isempty(app.z_mean_down))
                        app.z_m = double(app.z_mean_up + app.z_mean_down)/2;
                        app.z_s = 9.81*2/double(app.z_mean_up - app.z_mean_down);
                        app.ResZMean.Text = sprintf("%.2f",app.z_m);
                        app.ResZSens.Text = sprintf("%.2f",app.z_s);
                    end
                elseif event.Source==app.CDZBtn
                    app.z_mean_down = mean(double(app.serial_interface.received_data(4,1:end)));
                    if(~isempty(app.z_mean_up))
                        app.z_m = double(app.z_mean_up + app.z_mean_down)/2;
                        app.z_s = 9.81*2/double(app.z_mean_up - app.z_mean_down);
                        app.ResZMean.Text = sprintf("%.2f",app.z_m);
                        app.ResZSens.Text = sprintf("%.2f",app.z_s);
                    end
                end
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            x_mean = app.x_m;
            y_mean = app.y_m;
            z_mean = app.z_m;
            x_sens = app.x_s;
            y_sens = app.y_s;
            z_sens = app.z_s;
            save("sensitivity.mat","x_mean","x_sens","y_mean","y_sens","z_mean","z_sens")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.az = uiaxes(app.GridLayout);
            xlabel(app.az, 't')
            ylabel(app.az, 'Z')
            zlabel(app.az, 'Z')
            app.ay = uiaxes(app.GridLayout);
            xlabel(app.ay, 't')
            ylabel(app.ay, 'Y')
            zlabel(app.ay, 'Z')
            app.ax = uiaxes(app.GridLayout);
            xlabel(app.ax, 't')
            ylabel(app.ax, 'X')
            zlabel(app.ax, 'Z')
            app.iy = uigridlayout(app.GridLayout);
            app.CDYBtn = uibutton(app.iy, 'state');
            app.CDYBtn.ValueChangedFcn = createCallbackFcn(app, @CUXBtnValueChanged, true);
            app.CDYBtn.Text = 'Calibrate Down';
            app.CUYBtn = uibutton(app.iy, 'state');
            app.CUYBtn.ValueChangedFcn = createCallbackFcn(app, @CUXBtnValueChanged, true);
            app.CUYBtn.Text = 'Calibrate Up';
            app.iz = uigridlayout(app.GridLayout);
            app.CUZBtn = uibutton(app.iz, 'state');
            app.CUZBtn.ValueChangedFcn = createCallbackFcn(app, @CUXBtnValueChanged, true);
            app.CUZBtn.Text = 'Calibrate Up';
            app.CDZBtn = uibutton(app.iz, 'state');
            app.CDZBtn.ValueChangedFcn = createCallbackFcn(app, @CUXBtnValueChanged, true);
            app.CDZBtn.Text = 'Calibrate Down';
            app.SerialDeviceDropDownLabel = uilabel(app.GridLayout);
            app.SerialDeviceDropDownLabel.Text = 'Serial Device';
            app.SDDD = uidropdown(app.GridLayout);
            app.SDDD.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6'};
            app.SDDD.Value = 'COM1';
            app.CBtn = uibutton(app.GridLayout, 'push');
            app.CBtn.ButtonPushedFcn = createCallbackFcn(app, @CBtnButtonPushed, true);
            app.CBtn.Text = 'Connect';
            app.ScnBtn = uibutton(app.GridLayout, 'push');
            app.ScnBtn.ButtonPushedFcn = createCallbackFcn(app, @ScnBtnButtonPushed, true);
            app.ScnBtn.Text = 'Scan Serial Device';
            app.BaudrateEditFieldLabel = uilabel(app.GridLayout);
            app.BaudrateEditFieldLabel.Text = 'Baudrate';
            app.BaudrateEditField = uieditfield(app.GridLayout, 'numeric');
            app.BaudrateEditField.ValueDisplayFormat = '%11.100g';
            app.BaudrateEditField.Value = 115200;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.XMeanLabel = uilabel(app.GridLayout2);
            app.XMeanLabel.Text = 'X Mean';
            app.XSensLabel = uilabel(app.GridLayout2);
            app.XSensLabel.Text = 'X Sens';
            app.GridLayout2_4 = uigridlayout(app.GridLayout);
            app.ResXMean = uilabel(app.GridLayout2_4);
            app.ResXMean.Text = '0';
            app.ResXSens = uilabel(app.GridLayout2_4);
            app.ResXSens.Text = '0';
            app.GridLayout2_5 = uigridlayout(app.GridLayout);
            app.ResYMean = uilabel(app.GridLayout2_5);
            app.ResYMean.Text = '0';
            app.ResYSens = uilabel(app.GridLayout2_5);
            app.ResYSens.Text = '0';
            app.GridLayout2_6 = uigridlayout(app.GridLayout);
            app.ResZMean = uilabel(app.GridLayout2_6);
            app.ResZMean.Text = '0';
            app.ResZSens = uilabel(app.GridLayout2_6);
            app.ResZSens.Text = '0';
            app.GridLayout2_7 = uigridlayout(app.GridLayout);
            app.YMeanLabel = uilabel(app.GridLayout2_7);
            app.YMeanLabel.Text = 'Y Mean';
            app.YSensLabel_2 = uilabel(app.GridLayout2_7);
            app.YSensLabel_2.Text = 'Y Sens';
            app.GridLayout2_8 = uigridlayout(app.GridLayout);
            app.ZMeanLabel = uilabel(app.GridLayout2_8);
            app.ZMeanLabel.Text = 'Z Mean';
            app.ZSensLabel = uilabel(app.GridLayout2_8);
            app.ZSensLabel.Text = 'Z Sens';
            app.ix = uigridlayout(app.GridLayout);
            app.CUXBtn = uibutton(app.ix, 'state');
            app.CUXBtn.ValueChangedFcn = createCallbackFcn(app, @CUXBtnValueChanged, true);
            app.CUXBtn.Text = 'Calibrate Up';
            app.CDXBtn = uibutton(app.ix, 'state');
            app.CDXBtn.ValueChangedFcn = createCallbackFcn(app, @CUXBtnValueChanged, true);
            app.CDXBtn.Text = 'Calibrate Down';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADXL335CalibrationProgram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end